classdef arousal_states < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        HomeTab                  matlab.ui.container.Tab
        NextButton               matlab.ui.control.StateButton
        SubjectIDEditFieldLabel  matlab.ui.control.Label
        SubjectIDEditField       matlab.ui.control.EditField
        RunEditFieldLabel        matlab.ui.control.Label
        RunEditField             matlab.ui.control.EditField
        DataTab                  matlab.ui.container.Tab
        Panel                    matlab.ui.container.Panel
        UIAxesClickTimes         matlab.ui.control.UIAxes
        UIAxesEEGSpec            matlab.ui.control.UIAxes
        ArousalStatesPanel       matlab.ui.container.Panel
        DeltaCheckBox            matlab.ui.control.CheckBox
        ThetaCheckBox            matlab.ui.control.CheckBox
        AlphaCheckBox            matlab.ui.control.CheckBox
        BetaCheckBox             matlab.ui.control.CheckBox
        BackButton               matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        subjectID       %subject folder in directory
        run             %run folder value in directory 
        subjectnum      %user input
        runnum          %userinput
        ClickTimes      %loaded data for plots
        arousTimeEEG    %needed to graph the EEGspec
        spec            %needed to graph the EEGspec
        f               %needed to graph the EEGspec
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NextButton
        function NextButtonValueChanged(app, event)
            value1 = app.NextButton.Value;
            if value1 ~=0
                
         %% Click Time Figure
            %Changes the tab and loads the data
                app.TabGroup.SelectedTab = app.DataTab;
                ct = load(['/Volumes/Research/eng_research_lewislab/data/' ...
                    'osceeg_frommgh/',app.subjectID,'/behav/',app.run,'_clicktimes.mat']);
                app.ClickTimes = ct.clicktimes;           
            %Plots the clicker times   
                Y = ones(size(app.ClickTimes));
                X = app.ClickTimes;
                min = X(1)- 50;
                max = X(length(X))+ 50;
                scatter(app.UIAxesClickTimes,X,Y,'Marker','*');
                app.UIAxesClickTimes.XLim = [min max];
                
         %% EEGSpec Figure
               [app.arousTimeEEG,app.spec,app.f] = specgram_v2(app.subjectID,app.run,app.ClickTimes);
            %Plots the specgram
                imagesc(app.UIAxesEEGSpec,app.arousTimeEEG,app.f,log10(app.spec')); %10*log10(S2)'
                colormap(app.UIAxesEEGSpec,'jet');   
                app.UIAxesEEGSpec.YDir = 'normal';
                app.UIAxesEEGSpec.YLim = [0,25];
                
%             %Links the axis
%                 specaxes = app.UIAxesEEGSpec.XLim;
%                 linkaxes([app.UIAxesClickTimes app.UIAxesEEGSpec],'x');
%                 app.UIAxesEEGSpec.XLim = specaxes;
            end
        end

        % Value changed function: BackButton
        function BackButtonValueChanged(app, event)
            value2 = app.BackButton.Value;
            if value2 ~=0
                app.TabGroup.SelectedTab = app.HomeTab;
            end
        end

        % Value changed function: SubjectIDEditField
        function SubjectIDEditFieldValueChanged(app, event)
            app.subjectnum = app.SubjectIDEditField.Value;
            app.subjectID= ['osceeg',app.subjectnum];
        end

        % Value changed function: RunEditField
        function RunEditFieldValueChanged(app, event)
            app.runnum = app.RunEditField.Value;
            app.run = ['run',app.runnum];
        end

        % Callback function
        function TextAreaValueChanged(app, event)
            app.TextArea.Value = app.subjectID;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.NextButton = uibutton(app.HomeTab, 'state');
            app.NextButton.ValueChangedFcn = createCallbackFcn(app, @NextButtonValueChanged, true);
            app.NextButton.Text = 'Next';
            app.SubjectIDEditFieldLabel = uilabel(app.HomeTab);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID:';
            app.SubjectIDEditField = uieditfield(app.HomeTab, 'text');
            app.SubjectIDEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectIDEditFieldValueChanged, true);
            app.RunEditFieldLabel = uilabel(app.HomeTab);
            app.RunEditFieldLabel.Text = 'Run:';
            app.RunEditField = uieditfield(app.HomeTab, 'text');
            app.RunEditField.ValueChangedFcn = createCallbackFcn(app, @RunEditFieldValueChanged, true);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.Panel = uipanel(app.DataTab);
            app.UIAxesClickTimes = uiaxes(app.Panel);
            title(app.UIAxesClickTimes, 'Click Times')
            xlabel(app.UIAxesClickTimes, 'Time (sec)')
            ylabel(app.UIAxesClickTimes, 'Frequency (Hz)')
            app.UIAxesEEGSpec = uiaxes(app.Panel);
            title(app.UIAxesEEGSpec, 'EEG Spectrogram')
            xlabel(app.UIAxesEEGSpec, 'Time (sec)')
            ylabel(app.UIAxesEEGSpec, 'Frequency (Hz)')
            app.ArousalStatesPanel = uipanel(app.DataTab);
            app.ArousalStatesPanel.Title = 'Arousal States';
            app.DeltaCheckBox = uicheckbox(app.ArousalStatesPanel);
            app.DeltaCheckBox.Text = 'Delta';
            app.ThetaCheckBox = uicheckbox(app.ArousalStatesPanel);
            app.ThetaCheckBox.Text = 'Theta';
            app.AlphaCheckBox = uicheckbox(app.ArousalStatesPanel);
            app.AlphaCheckBox.Text = 'Alpha';
            app.BetaCheckBox = uicheckbox(app.ArousalStatesPanel);
            app.BetaCheckBox.Text = 'Beta';
            app.BackButton = uibutton(app.ArousalStatesPanel, 'state');
            app.BackButton.ValueChangedFcn = createCallbackFcn(app, @BackButtonValueChanged, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arousal_states

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end